classdef appMaquinaCDI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UI                           matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        MENU                         matlab.ui.container.Tab
        Title                        matlab.ui.control.Label
        Subtitle                     matlab.ui.control.Label
        InsiraumafunoEditFieldLabel  matlab.ui.control.Label
        funcao                       matlab.ui.control.EditField
        aEditFieldLabel              matlab.ui.control.Label
        a_field                      matlab.ui.control.NumericEditField
        bEditFieldLabel              matlab.ui.control.Label
        b_field                      matlab.ui.control.NumericEditField
        nEditFieldLabel              matlab.ui.control.Label
        n_field                      matlab.ui.control.NumericEditField
        CalcularBotao                matlab.ui.control.Button
        MtodosDropDownLabel          matlab.ui.control.Label
        Metodos                      matlab.ui.control.DropDown
        CDI_Botoes                   matlab.ui.container.ButtonGroup
        CalculoIntegral              matlab.ui.control.ToggleButton
        CalculoDiferencial           matlab.ui.control.ToggleButton
        SoluoExataEditFieldLabel     matlab.ui.control.Label
        SolExata                     matlab.ui.control.EditField
        ONE_VR                       matlab.ui.container.Tab
        Label1                       matlab.ui.control.Label
        SubLabel1                    matlab.ui.control.Label
        Tabela1                      matlab.ui.control.Table
        Grelha1                      matlab.ui.control.CheckBox
        Subtitle1                    matlab.ui.control.Label
        Graph1                       matlab.ui.control.UIAxes
        TWO_VR                       matlab.ui.container.Tab
        Label2                       matlab.ui.control.Label
        SubLabel2                    matlab.ui.control.Label
        Tabela2                      matlab.ui.control.Table
        Calcular2                    matlab.ui.control.Button
        Limpar2                      matlab.ui.control.Button
        Sair2                        matlab.ui.control.Button
        Text2                        matlab.ui.control.TextArea
        aEditField_2Label            matlab.ui.control.Label
        a2                           matlab.ui.control.NumericEditField
        fxyEditFieldLabel            matlab.ui.control.Label
        f2                           matlab.ui.control.EditField
        hEditField_2Label            matlab.ui.control.Label
        h2                           matlab.ui.control.NumericEditField
        bEditField_2Label            matlab.ui.control.Label
        b2                           matlab.ui.control.NumericEditField
        MtodosDropDown_2Label        matlab.ui.control.Label
        Metodos2                     matlab.ui.control.DropDown
        Grafico2                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularBotao
        function Calcular1ButtonPushed(app, event)
            syms y(x)
            cla(app.Graph1);
            
            botaoSelecionado = app.CDI_Botoes.SelectedObject.Text;

            switch botaoSelecionado
                case 'Cálculo Diferencial'
                    app.Subtitle1.Text = botaoSelecionado;
                case 'Cálculo Integral'
                    app.Subtitle1.Text = botaoSelecionado;
            end
            
           
            %%%%% Verificação do Input %%%%%
            strF = app.funcao.Value; % Obter o valor no campo da função f
            
            f = @(x) eval(vectorize(strF)); % Definir a nossa função f
            try % Testar se a função inserida é válida
                f(x);
            catch
                errordlg("Introduza uma função em x","Erro");
                return
            end
            
            
            a = app.a_field.Value; % Obter o valor do campo A
            if (not(isscalar(a) && isreal(a))) % O valor A tem de ser real
                errordlg("O número introduzido para a é inválido","Erro");
                return
            end
            
            b = app.b_field.Value; % Obter o valor do campo B
            if (not(isscalar(b) && isreal(b) && b>a)) % O valor B tem de ser real e maior que A
                errordlg("O número introduzido para b é inválido","Erro");
                return
            end
        
            n = app.n_field.Value; % Obter o valor do campo N
            if (not(isscalar(n)))
                errordlg("O número introduzido para n é inválido","Erro");
                return
            end
            
            h = (a-b)/n;
            
            switch botaoSelecionado
                case 'Cálculo Diferencial'
                    d = diff(f,x);
                    g = @(x) eval(vectorize(d));
                    fplot(app.Graph1,f,'-bo','LineWidth',1);
                    hold(app.Graph1, "on");
                    fplot(app.Graph1,g,'--r','LineWidth',1);
                    hold(app.Graph1, "on");
                    xlim(app.Graph1, [a b]);
                case 'Cálculo Integral'
                    d = int(f,x);
                    g = @(x) eval(vectorize(d));
                    fplot(app.Graph1,f,'-bo','LineWidth',1);
                    hold(app.Graph1, "on");
                    fplot(app.Graph1,g,'--r','LineWidth',1);
                    hold(app.Graph1, "on");
                    xlim(app.Graph1, [a b]);
            end
            
        end

        % Value changed function: funcao
        function funcaoValueChanged(app, event)
            value = app.funcao.Value;
            botaoSelecionado = app.CDI_Botoes.SelectedObject.Text;
            
            syms y(x)
            f = @(x) eval(vectorize(value));
            switch botaoSelecionado
                case 'Cálculo Diferencial'
                    d = diff(f,x);
                    app.SolExata.Value = string(d);
                case 'Cálculo Integral'
                    d = int(f,x);
                    app.SolExata.Value = string(d);
            end
            
            
        end

        % Selection changed function: CDI_Botoes
        function CDI_BotoesSelectionChanged(app, event)
            value = app.funcao.Value;
            selectedButton = app.CDI_Botoes.SelectedObject.Text;
            dropdown = app.Metodos;
            
            syms y(x)
            f = @(x) eval(vectorize(value));
            switch selectedButton
                case 'Cálculo Diferencial'
                    dropdown.Items = {  
                                            'D.F. Progressivas (2 pontos)'
                                            'D.F. Regressivas (2 pontos)'
                                            'D.F. Progressivas (3 pontos)'
                                            'D.F. Regressivas (3 pontos)'
                                            'D.F. Centradas (3 pontos)'
                                            'D.F. 2ª Derivada (3 pontos)'
                                            'Todos'
                                     };
                    
                    d = diff(f,x);
                    app.SolExata.Value = string(d);
                case 'Cálculo Integral'                    
                    dropdown.Items = {  
                                            'Regra dos Trapézios'
                                            'Regra de Simpson'
                                            'Todos'
                                     };
                    
                    d = int(f,x);
                    app.SolExata.Value = string(d);
            end
        end

        % Value changed function: Grelha1
        function Grelha1ValueChanged(app, event)
            value = app.Grelha1.Value;
            switch value
                case 1
                    grid(app.Graph1, "on");
                case 0
                    grid(app.Graph1, "off");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UI = uifigure('Visible', 'off');
            app.UI.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UI);
            app.MENU = uitab(app.TabGroup);
            app.MENU.Title = 'Menu';
            app.Title = uilabel(app.MENU);
            app.Title.Text = 'Máquina';
            app.Subtitle = uilabel(app.MENU);
            app.Subtitle.Text = 'Cáculo Diferencial e Integral';
            app.InsiraumafunoEditFieldLabel = uilabel(app.MENU);
            app.InsiraumafunoEditFieldLabel.Text = 'Insira uma função';
            app.funcao = uieditfield(app.MENU, 'text');
            app.funcao.ValueChangedFcn = createCallbackFcn(app, @funcaoValueChanged, true);
            app.funcao.Value = 'sin(x)';
            app.aEditFieldLabel = uilabel(app.MENU);
            app.aEditFieldLabel.Text = 'a';
            app.a_field = uieditfield(app.MENU, 'numeric');
            app.bEditFieldLabel = uilabel(app.MENU);
            app.bEditFieldLabel.Text = 'b';
            app.b_field = uieditfield(app.MENU, 'numeric');
            app.b_field.Value = 6.286;
            app.nEditFieldLabel = uilabel(app.MENU);
            app.nEditFieldLabel.Text = 'n';
            app.n_field = uieditfield(app.MENU, 'numeric');
            app.n_field.Value = 20;
            app.CalcularBotao = uibutton(app.MENU, 'push');
            app.CalcularBotao.ButtonPushedFcn = createCallbackFcn(app, @Calcular1ButtonPushed, true);
            app.CalcularBotao.Text = 'Calcular';
            app.MtodosDropDownLabel = uilabel(app.MENU);
            app.MtodosDropDownLabel.Text = 'Métodos';
            app.Metodos = uidropdown(app.MENU);
            app.Metodos.Items = {'D.F. Progressivas (2 pontos)', 'D.F. Regressivas (2 pontos)', 'D.F. Progressivas (3 pontos)', 'D.F. Regressivas (3 pontos)', 'D.F. Centradas (3 pontos)', 'D.F. 2ª Derivada (3 pontos)', 'Todos'};
            app.Metodos.Value = 'D.F. Progressivas (2 pontos)';
            app.CDI_Botoes = uibuttongroup(app.MENU);
            app.CDI_Botoes.SelectionChangedFcn = createCallbackFcn(app, @CDI_BotoesSelectionChanged, true);
            app.CalculoIntegral = uitogglebutton(app.CDI_Botoes);
            app.CalculoIntegral.Text = 'Cálculo Diferencial';
            app.CalculoIntegral.Value = true;
            app.CalculoDiferencial = uitogglebutton(app.CDI_Botoes);
            app.CalculoDiferencial.Text = 'Cálculo Integral';
            app.SoluoExataEditFieldLabel = uilabel(app.MENU);
            app.SoluoExataEditFieldLabel.Text = 'Solução Exata';
            app.SolExata = uieditfield(app.MENU, 'text');
            app.SolExata.Editable = 'off';
            app.SolExata.Value = 'cos(x)';
            app.ONE_VR = uitab(app.TabGroup);
            app.ONE_VR.Title = 'Função real de 1 variável real: y = f(x)';
            app.Label1 = uilabel(app.ONE_VR);
            app.Label1.Text = 'Função real de 1 variável real';
            app.SubLabel1 = uilabel(app.ONE_VR);
            app.SubLabel1.Text = 'y = f(x)';
            app.Tabela1 = uitable(app.ONE_VR);
            app.Tabela1.ColumnName = {''; ''; ''; ''};
            app.Tabela1.RowName = {};
            app.Grelha1 = uicheckbox(app.ONE_VR);
            app.Grelha1.ValueChangedFcn = createCallbackFcn(app, @Grelha1ValueChanged, true);
            app.Grelha1.Text = 'Grelha';
            app.Subtitle1 = uilabel(app.ONE_VR);
            app.Subtitle1.Text = 'Cálculo Diferencial';
            app.Graph1 = uiaxes(app.ONE_VR);
            zlabel(app.Graph1, 'Z')
            app.TWO_VR = uitab(app.TabGroup);
            app.TWO_VR.Title = 'Função real de 2 variáveis reais: z = f(x,y)';
            app.Label2 = uilabel(app.TWO_VR);
            app.Label2.Text = 'Função real de 2 variáveis reais';
            app.SubLabel2 = uilabel(app.TWO_VR);
            app.SubLabel2.Text = 'z = f(x,y)';
            app.Tabela2 = uitable(app.TWO_VR);
            app.Tabela2.ColumnName = {''; ''; ''; ''};
            app.Tabela2.RowName = {};
            app.Calcular2 = uibutton(app.TWO_VR, 'push');
            app.Calcular2.Text = 'Calcular';
            app.Limpar2 = uibutton(app.TWO_VR, 'push');
            app.Limpar2.Text = 'Limpar';
            app.Sair2 = uibutton(app.TWO_VR, 'push');
            app.Sair2.Text = 'Sair';
            app.Text2 = uitextarea(app.TWO_VR);
            app.Text2.Editable = 'off';
            app.aEditField_2Label = uilabel(app.TWO_VR);
            app.aEditField_2Label.Text = 'a';
            app.a2 = uieditfield(app.TWO_VR, 'numeric');
            app.fxyEditFieldLabel = uilabel(app.TWO_VR);
            app.fxyEditFieldLabel.Text = 'f(x,y)';
            app.f2 = uieditfield(app.TWO_VR, 'text');
            app.hEditField_2Label = uilabel(app.TWO_VR);
            app.hEditField_2Label.Text = 'h';
            app.h2 = uieditfield(app.TWO_VR, 'numeric');
            app.bEditField_2Label = uilabel(app.TWO_VR);
            app.bEditField_2Label.Text = 'b';
            app.b2 = uieditfield(app.TWO_VR, 'numeric');
            app.MtodosDropDown_2Label = uilabel(app.TWO_VR);
            app.MtodosDropDown_2Label.Text = 'Métodos';
            app.Metodos2 = uidropdown(app.TWO_VR);
            app.Metodos2.Items = {'DF. Progressivas (2 pontos)', 'DF. Regressivas (2 pontos)', 'DF. Progressivas (3 pontos)', 'DF. Regressivas (3 pontos)', 'DF. Centradas (3 pontos)', 'DF. 2ª Derivada (3 pontos)', 'Todos'};
            app.Metodos2.Value = 'DF. Progressivas (2 pontos)';
            app.Grafico2 = uiaxes(app.TWO_VR);
            zlabel(app.Grafico2, 'Z')
            % Show the figure after all components are created
            app.UI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMaquinaCDI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UI)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UI)
        end
    end
end